classdef Advanced_Example_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExamplewithDatetimeSliderandDatetimeRangeSliderUIFigure  matlab.ui.Figure
        DatetimeRangeSlider  DatetimeRangeSlider
        DatetimeSlider       DatetimeSlider
        UIAxes               matlab.ui.control.UIAxes
        ContextMenu          matlab.ui.container.ContextMenu
        MMMddMenu            matlab.ui.container.Menu
        MMMdduuuuMenu        matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Line
        ROI
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            DateRange = app.DatetimeSlider.Limits(1):app.DatetimeSlider.Limits(2);
            plot(app.UIAxes,DateRange,rand(size(DateRange)))
            hold(app.UIAxes,"on")

            app.Line = xline(app.UIAxes,app.DatetimeSlider.Value(1));
            app.ROI = xregion(app.UIAxes,app.DatetimeRangeSlider.Value(1),app.DatetimeRangeSlider.Value(2));
            app.ROI.ContextMenu = app.ContextMenu;
        end

        % Menu selected function: MMMddMenu
        function MMMddMenuSelected(app, event)
            app.DatetimeSlider.DateFormat = "MMM dd";
        end

        % Menu selected function: MMMdduuuuMenu
        function MMMdduuuuMenuSelected(app, event)
            app.DatetimeSlider.DateFormat = "MMM d, uuuu";
        end

        % Callback function: DatetimeRangeSlider, DatetimeRangeSlider, 
        % ...and 3 other components
        function DatetimeSliderValueChanged(app, event)
            if event.EventName == "LimitsChanged"
                app.UIAxes.XLim = event.Source.Limits;%[app.DatetimeSlider.Limits(1),app.DatetimeSlider.Limits(2)];
                if ~isequal(app.DatetimeRangeSlider.Limits,app.UIAxes.XLim)
                    app.DatetimeRangeSlider.Limits = app.UIAxes.XLim;
                end
                if ~isequal(app.DatetimeSlider.Limits,app.UIAxes.XLim)
                    app.DatetimeSlider.Limits = app.UIAxes.XLim;
                end
            end
            
            app.Line.Value = app.DatetimeSlider.Value(1);         
            app.ROI.Value = [app.DatetimeRangeSlider.Value(1),app.DatetimeRangeSlider.Value(2)]; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExamplewithDatetimeSliderandDatetimeRangeSliderUIFigure = uifigure('Visible', 'off');
            app.ExamplewithDatetimeSliderandDatetimeRangeSliderUIFigure.Name = 'Example with Datetime Slider and Datetime Range Slider';
            app.UIAxes = uiaxes(app.ExamplewithDatetimeSliderandDatetimeRangeSliderUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DatetimeSlider = DatetimeSlider(app.ExamplewithDatetimeSliderandDatetimeRangeSliderUIFigure);
            app.DatetimeSlider.LimitsChangedFcn = createCallbackFcn(app, @DatetimeSliderValueChanged, true);
            app.DatetimeSlider.ValueChangedFcn = createCallbackFcn(app, @DatetimeSliderValueChanged, true);
            app.DatetimeRangeSlider = DatetimeRangeSlider(app.ExamplewithDatetimeSliderandDatetimeRangeSliderUIFigure);
            app.DatetimeRangeSlider.DateFormatChangedFcn = createCallbackFcn(app, @DatetimeSliderValueChanged, true);
            app.DatetimeRangeSlider.LimitsChangedFcn = createCallbackFcn(app, @DatetimeSliderValueChanged, true);
            app.DatetimeRangeSlider.ValueChangedFcn = createCallbackFcn(app, @DatetimeSliderValueChanged, true);
            app.ContextMenu = uicontextmenu(app.ExamplewithDatetimeSliderandDatetimeRangeSliderUIFigure);
            app.MMMddMenu = uimenu(app.ContextMenu);
            app.MMMddMenu.MenuSelectedFcn = createCallbackFcn(app, @MMMddMenuSelected, true);
            app.MMMddMenu.Text = 'MMMdd';
            app.MMMdduuuuMenu = uimenu(app.ContextMenu);
            app.MMMdduuuuMenu.MenuSelectedFcn = createCallbackFcn(app, @MMMdduuuuMenuSelected, true);
            app.MMMdduuuuMenu.Text = 'MMMdduuuu';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.ExamplewithDatetimeSliderandDatetimeRangeSliderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Advanced_Example_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExamplewithDatetimeSliderandDatetimeRangeSliderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExamplewithDatetimeSliderandDatetimeRangeSliderUIFigure)
        end
    end
end