classdef DatetimeRangeSlider_Example_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExamplewithDatetimeRangeSliderUIFigure  matlab.ui.Figure
        DatetimeRangeSlider  DatetimeRangeSlider
        UIAxes               matlab.ui.control.UIAxes
        ContextMenu          matlab.ui.container.ContextMenu
        MMMddMenu            matlab.ui.container.Menu
        MMMdduuuuMenu        matlab.ui.container.Menu
    end

    
    properties (Access = public)
        ROI
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            DateRange = app.DatetimeRangeSlider.Limits(1):app.DatetimeRangeSlider.Limits(2);
            plot(app.UIAxes,DateRange,rand(size(DateRange)))
            hold(app.UIAxes,"on")

            app.ROI = xregion(app.UIAxes,app.DatetimeRangeSlider.Value(1),app.DatetimeRangeSlider.Value(2));

            % app.UIAxes.ContextMenu =
            app.ROI.ContextMenu = app.ContextMenu;%uicontextmenu(app.ExamplewithDatetimeRangeSliderUIFigure);
            % uimenu(app.ROI.ContextMenu, 'Text','something');
        end

        % Menu selected function: MMMddMenu
        function MMMddMenuSelected(app, event)
            app.DatetimeRangeSlider.DateFormat = "MMM dd";
        end

        % Menu selected function: MMMdduuuuMenu
        function MMMdduuuuMenuSelected(app, event)
            app.DatetimeRangeSlider.DateFormat = "MMM d, uuuu";
        end

        % Callback function: DatetimeRangeSlider, DatetimeRangeSlider
        function DatetimeRangeSliderValueChanged(app, event)
            app.UIAxes.XLim = [app.DatetimeRangeSlider.Limits(1),app.DatetimeRangeSlider.Limits(2)];
            app.ROI.Value = [app.DatetimeRangeSlider.Value(1),app.DatetimeRangeSlider.Value(2)];  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExamplewithDatetimeRangeSliderUIFigure = uifigure('Visible', 'off');
            app.ExamplewithDatetimeRangeSliderUIFigure.Name = 'Example with Datetime Range Slider';
            app.UIAxes = uiaxes(app.ExamplewithDatetimeRangeSliderUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DatetimeRangeSlider = DatetimeRangeSlider(app.ExamplewithDatetimeRangeSliderUIFigure);
            app.DatetimeRangeSlider.Value = [datetime([2025 3 6]) datetime([2025 4 4])];
            app.DatetimeRangeSlider.Limits = [datetime([2025 3 6]) datetime([2025 4 6])];
            app.DatetimeRangeSlider.LimitsChangedFcn = createCallbackFcn(app, @DatetimeRangeSliderValueChanged, true);
            app.DatetimeRangeSlider.ValueChangedFcn = createCallbackFcn(app, @DatetimeRangeSliderValueChanged, true);
            app.ContextMenu = uicontextmenu(app.ExamplewithDatetimeRangeSliderUIFigure);
            app.MMMddMenu = uimenu(app.ContextMenu);
            app.MMMddMenu.MenuSelectedFcn = createCallbackFcn(app, @MMMddMenuSelected, true);
            app.MMMddMenu.Text = 'MMMdd';
            app.MMMdduuuuMenu = uimenu(app.ContextMenu);
            app.MMMdduuuuMenu.MenuSelectedFcn = createCallbackFcn(app, @MMMdduuuuMenuSelected, true);
            app.MMMdduuuuMenu.Text = 'MMMdduuuu';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            app.DatetimeRangeSlider.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.ExamplewithDatetimeRangeSliderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatetimeRangeSlider_Example_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExamplewithDatetimeRangeSliderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExamplewithDatetimeRangeSliderUIFigure)
        end
    end
end