classdef DatetimeSlider_Example_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExamplewithDatetimeSliderUIFigure  matlab.ui.Figure
        DatetimeSlider  DatetimeSlider
        UIAxes          matlab.ui.control.UIAxes
        ContextMenu     matlab.ui.container.ContextMenu
        MMMddMenu       matlab.ui.container.Menu
        MMMdduuuuMenu   matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Line
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            DateRange = app.DatetimeSlider.Limits(1):app.DatetimeSlider.Limits(2);
            plot(app.UIAxes,DateRange,rand(size(DateRange)))
            hold(app.UIAxes,"on")

            app.Line = xline(app.UIAxes,app.DatetimeSlider.Value(1));
        end

        % Menu selected function: MMMddMenu
        function MMMddMenuSelected(app, event)
            app.DatetimeSlider.DateFormat = "MMM dd";
        end

        % Menu selected function: MMMdduuuuMenu
        function MMMdduuuuMenuSelected(app, event)
            app.DatetimeSlider.DateFormat = "MMM d, uuuu";
        end

        % Callback function: DatetimeSlider, DatetimeSlider
        function DatetimeSliderValueChanged(app, event)
            app.UIAxes.XLim = [app.DatetimeSlider.Limits(1),app.DatetimeSlider.Limits(2)];
            app.Line.Value = app.DatetimeSlider.Value(1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExamplewithDatetimeSliderUIFigure = uifigure('Visible', 'off');
            app.ExamplewithDatetimeSliderUIFigure.Name = 'Example with Datetime Slider';
            app.UIAxes = uiaxes(app.ExamplewithDatetimeSliderUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DatetimeSlider = DatetimeSlider(app.ExamplewithDatetimeSliderUIFigure);
            app.DatetimeSlider.Value = datetime([2025 3 6]);
            app.DatetimeSlider.Limits = [datetime([2025 3 6]) datetime([2025 4 6])];
            app.DatetimeSlider.LimitsChangedFcn = createCallbackFcn(app, @DatetimeSliderValueChanged, true);
            app.DatetimeSlider.ValueChangedFcn = createCallbackFcn(app, @DatetimeSliderValueChanged, true);
            app.ContextMenu = uicontextmenu(app.ExamplewithDatetimeSliderUIFigure);
            app.MMMddMenu = uimenu(app.ContextMenu);
            app.MMMddMenu.MenuSelectedFcn = createCallbackFcn(app, @MMMddMenuSelected, true);
            app.MMMddMenu.Text = 'MMMdd';
            app.MMMdduuuuMenu = uimenu(app.ContextMenu);
            app.MMMdduuuuMenu.MenuSelectedFcn = createCallbackFcn(app, @MMMdduuuuMenuSelected, true);
            app.MMMdduuuuMenu.Text = 'MMMdduuuu';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.ExamplewithDatetimeSliderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatetimeSlider_Example_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExamplewithDatetimeSliderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExamplewithDatetimeSliderUIFigure)
        end
    end
end